classdef Oscilloscope_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Position2Knob_2         matlab.ui.control.Knob
        Position2Knob_2Label    matlab.ui.control.Label
        Scale2Knob_2            matlab.ui.control.Knob
        Scale2Knob_2Label       matlab.ui.control.Label
        Position2Knob           matlab.ui.control.Knob
        Position2KnobLabel      matlab.ui.control.Label
        Scale1Knob_2            matlab.ui.control.Knob
        Scale1Knob_2Label       matlab.ui.control.Label
        Position1Knob_2         matlab.ui.control.Knob
        Position1Knob_2Label    matlab.ui.control.Label
        Scale2Knob              matlab.ui.control.Knob
        Scale2KnobLabel         matlab.ui.control.Label
        Scale1Knob              matlab.ui.control.Knob
        Scale1KnobLabel         matlab.ui.control.Label
        Position1Knob           matlab.ui.control.Knob
        Position1KnobLabel      matlab.ui.control.Label
        HorizontalLabel         matlab.ui.control.Label
        VerticalLabel           matlab.ui.control.Label
        Amplitude2Slider        matlab.ui.control.Slider
        Amplitude2SliderLabel   matlab.ui.control.Label
        Amplitude1Slider        matlab.ui.control.Slider
        Amplitude1SliderLabel   matlab.ui.control.Label
        Frequency2Slider        matlab.ui.control.Slider
        Frequency2SliderLabel   matlab.ui.control.Label
        Frequency1Slider        matlab.ui.control.Slider
        Frequency1SliderLabel   matlab.ui.control.Label
        waveform2DropDown       matlab.ui.control.DropDown
        waveform2DropDownLabel  matlab.ui.control.Label
        waveform1DropDown       matlab.ui.control.DropDown
        waveform1DropDownLabel  matlab.ui.control.Label
        Channel2Switch          matlab.ui.control.Switch
        Channel2SwitchLabel     matlab.ui.control.Label
        Channel1Switch          matlab.ui.control.Switch
        Channel1SwitchLabel     matlab.ui.control.Label
        GenerateButton_2        matlab.ui.control.Button
        GenerateButton          matlab.ui.control.Button
        WaveformGeneratorLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Channel1Switch
        function Channel1SwitchValueChanged(app, event)
            value = app.Channel1Switch.Value;
            
            componentsChannel1 = [app.waveform1DropDown, app.Amplitude1Slider, app.Frequency1Slider, app.Position1Knob, app.Position1Knob_2, app.Scale1Knob, app.Scale1Knob_2];
            if value 
                componentsChannel1state = 'on';
            else %if the switch is turned off you can't access the components for channel 2
                componentsChannel1state = 'off';
            end
            set(componentsChannel1, 'Enable', componentsChannel1state);
        end

        % Value changed function: waveform1DropDown
        function waveform1DropDownValueChanged(app, event)
            value = app.waveform1DropDown.Value;
           
           if value == 1 %when the sine wave option is selected
                out = sim('waveform_sine');
                wksData = get(out,'simout');
                assignin('base','wksData',wksData);
                set_param('waveform_sine/Sine Wave','Frequency','app.Frequency1Slider.Value');
                set_param('waveform_sine/Sine Wave','Amplitude','app.Amplitude1Slider.Value'); 
                plot(app.UIFigure,wksData);
                
           elseif value == 2 %when the square wave option is selected
                out = sim('waveform_square');
                wksData = get(out,'simout');
                assignin('base','wksData',wksData);
                set_param('waveform_square/Square Wave Generator','Frequency','app.Frequency1Slider.Value');
                set_param('waveform_square/Square Wave Generator','Amplitude','app.Amplitude1Slider.Value');
                plot(app.UIFigure,wksData);
               
           elseif value == 3 %when the sawtooth wave option is selected
                out = sim('waveform_sawtooth');
                wksData = get(out,'simout');
                assignin('base','wksData',wksData);
                set_param('waveform_sawtooth/Sawtooth Generator','Frequency','app.Frequency1Slider.Value');
                set_param('waveform_sawtooth/Sawtooth Generator','Amplitude','app.Amplitude1Slider.Value');
                plot(app.UIFigure,wksData);
           end
           hold on
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Oscilloscope')
            xlabel(app.UIAxes, 'Time, (s)')
            ylabel(app.UIAxes, 'Voltage, (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8];
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.WaveformGeneratorLabel = uilabel(app.UIFigure);
            app.WaveformGeneratorLabel.Text = 'Waveform Generator';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.Text = 'Generate';
            app.GenerateButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_2.Text = 'Generate';
            app.Channel1SwitchLabel = uilabel(app.UIFigure);
            app.Channel1SwitchLabel.Text = 'Channel 1';
            app.Channel1Switch = uiswitch(app.UIFigure, 'slider');
            app.Channel1Switch.ItemsData = {'0', '1'};
            app.Channel1Switch.ValueChangedFcn = createCallbackFcn(app, @Channel1SwitchValueChanged, true);
            app.Channel1Switch.Value = '0';
            app.Channel2SwitchLabel = uilabel(app.UIFigure);
            app.Channel2SwitchLabel.Text = 'Channel 2';
            app.Channel2Switch = uiswitch(app.UIFigure, 'slider');
            app.Channel2Switch.ItemsData = {'0', '1'};
            app.Channel2Switch.Value = '0';
            app.waveform1DropDownLabel = uilabel(app.UIFigure);
            app.waveform1DropDownLabel.Text = 'waveform 1';
            app.waveform1DropDown = uidropdown(app.UIFigure);
            app.waveform1DropDown.Items = {'sine', 'square', 'sawtooth'};
            app.waveform1DropDown.ItemsData = {'1', '2', '3'};
            app.waveform1DropDown.ValueChangedFcn = createCallbackFcn(app, @waveform1DropDownValueChanged, true);
            app.waveform1DropDown.Value = '1';
            app.waveform2DropDownLabel = uilabel(app.UIFigure);
            app.waveform2DropDownLabel.Text = 'waveform 2';
            app.waveform2DropDown = uidropdown(app.UIFigure);
            app.waveform2DropDown.Items = {'sine', 'square', 'sawtooth'};
            app.waveform2DropDown.ItemsData = {'1', '2', '3'};
            app.waveform2DropDown.Value = '1';
            app.Frequency1SliderLabel = uilabel(app.UIFigure);
            app.Frequency1SliderLabel.Text = 'Frequency 1';
            app.Frequency1Slider = uislider(app.UIFigure);
            app.Frequency2SliderLabel = uilabel(app.UIFigure);
            app.Frequency2SliderLabel.Text = 'Frequency 2';
            app.Frequency2Slider = uislider(app.UIFigure);
            app.Amplitude1SliderLabel = uilabel(app.UIFigure);
            app.Amplitude1SliderLabel.Text = 'Amplitude 1';
            app.Amplitude1Slider = uislider(app.UIFigure);
            app.Amplitude2SliderLabel = uilabel(app.UIFigure);
            app.Amplitude2SliderLabel.Text = 'Amplitude 2';
            app.Amplitude2Slider = uislider(app.UIFigure);
            app.VerticalLabel = uilabel(app.UIFigure);
            app.VerticalLabel.Text = 'Vertical';
            app.HorizontalLabel = uilabel(app.UIFigure);
            app.HorizontalLabel.Text = 'Horizontal';
            app.Position1KnobLabel = uilabel(app.UIFigure);
            app.Position1KnobLabel.Text = 'Position 1';
            app.Position1Knob = uiknob(app.UIFigure, 'continuous');
            app.Position1Knob.Limits = [-5 5];
            app.Position1Knob.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Position1Knob.MinorTicks = [];
            app.Scale1KnobLabel = uilabel(app.UIFigure);
            app.Scale1KnobLabel.Text = 'Scale 1';
            app.Scale1Knob = uiknob(app.UIFigure, 'continuous');
            app.Scale1Knob.Limits = [0 20];
            app.Scale1Knob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.Scale1Knob.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.Scale1Knob.MinorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.Scale2KnobLabel = uilabel(app.UIFigure);
            app.Scale2KnobLabel.Text = 'Scale 2';
            app.Scale2Knob = uiknob(app.UIFigure, 'continuous');
            app.Scale2Knob.Limits = [0 20];
            app.Scale2Knob.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.Scale2Knob.MinorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.Position1Knob_2Label = uilabel(app.UIFigure);
            app.Position1Knob_2Label.Text = 'Position 1';
            app.Position1Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Position1Knob_2.Limits = [0 8];
            app.Scale1Knob_2Label = uilabel(app.UIFigure);
            app.Scale1Knob_2Label.Text = 'Scale 1';
            app.Scale1Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Scale1Knob_2.Limits = [0 2];
            app.Position2KnobLabel = uilabel(app.UIFigure);
            app.Position2KnobLabel.Text = 'Position 2';
            app.Position2Knob = uiknob(app.UIFigure, 'continuous');
            app.Position2Knob.Limits = [0 8];
            app.Scale2Knob_2Label = uilabel(app.UIFigure);
            app.Scale2Knob_2Label.Text = 'Scale 2';
            app.Scale2Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Scale2Knob_2.Limits = [0 2];
            app.Position2Knob_2Label = uilabel(app.UIFigure);
            app.Position2Knob_2Label.Text = 'Position 2';
            app.Position2Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Position2Knob_2.Limits = [-5 5];
            app.Position2Knob_2.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Position2Knob_2.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Oscilloscope_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end