classdef oscilloscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WaveformGeneratorLabel   matlab.ui.control.Label
        Amplitude2Slider_2       matlab.ui.control.Slider
        Amplitude2Slider_2Label  matlab.ui.control.Label
        Amplitude1Slider         matlab.ui.control.Slider
        Amplitude1SliderLabel    matlab.ui.control.Label
        Frequency2Slider         matlab.ui.control.Slider
        Frequency2SliderLabel    matlab.ui.control.Label
        Frequency1Slider         matlab.ui.control.Slider
        Frequency1SliderLabel    matlab.ui.control.Label
        TimeSlider               matlab.ui.control.Slider
        TimeSliderLabel          matlab.ui.control.Label
        waveform2DropDown        matlab.ui.control.DropDown
        waveform2DropDownLabel   matlab.ui.control.Label
        waveform1DropDown        matlab.ui.control.DropDown
        waveform1Label           matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        TriggerKnob              matlab.ui.control.Knob
        TriggerKnobLabel         matlab.ui.control.Label
        HorizontalLabel          matlab.ui.control.Label
        VerticalLabel            matlab.ui.control.Label
        Scale2Knob               matlab.ui.control.Knob
        Scale2KnobLabel          matlab.ui.control.Label
        Position2Knob            matlab.ui.control.Knob
        Position2KnobLabel       matlab.ui.control.Label
        Scale1Knob               matlab.ui.control.Knob
        Scale1KnobLabel          matlab.ui.control.Label
        Position1Knob            matlab.ui.control.Knob
        Position1KnobLabel       matlab.ui.control.Label
        Scale2Knob_2             matlab.ui.control.Knob
        Scale2Knob_2Label        matlab.ui.control.Label
        Scale1Knob_2             matlab.ui.control.Knob
        Scale1Knob_2Label        matlab.ui.control.Label
        Position1Knob_2          matlab.ui.control.Knob
        Position2Knob_2          matlab.ui.control.Knob
        Position2Knob_2Label     matlab.ui.control.Label
        Position1Knob_2Label     matlab.ui.control.Label
        Channel2Switch           matlab.ui.control.Switch
        Channel2SwitchLabel      matlab.ui.control.Label
        Channel1Switch           matlab.ui.control.Switch
        Channel1SwitchLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: waveform1DropDown
        function waveform1DropDownClicked(app, event)

            
        end

        % Value changed function: waveform1DropDown
        function waveform1DropDownValueChanged(app, event)
    
        end

        % Value changed function: Channel1Switch
        function Channel1SwitchValueChanged(app, event)
            value = app.Channel1Switch.Value;
            componentsChannel1 = [app.waveform1DropDown, app.Amplitude1Slider, app.Frequency1Slider, app.Position1Knob, app.Position1Knob_2, app.Scale1Knob, app.Scale1Knob_2];
            if value
                componentsChannel1state = 'on';
            else
                componentsChannel1state = 'off';
            end
            set(componentsChannel1, 'Enable', componentsChannel1state);
            
        end

        % Value changed function: Channel2Switch
        function Channel2SwitchValueChanged(app, event)
            value = app.Channel2Switch.Value;
            componentsChannel2 = [app.waveform2DropDown, app.Amplitude2Slider, app.Frequency2Slider, app.Position2Knob, app.Position2Knob_2, app.Scale2Knob, app.Scale2Knob_2];
            if value
                componentsChannel2state = 'on';
            else
                componentsChannel2state = 'off';
            end
            set(componentsChannel2state, 'Enable', componentsChannel2state);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Oscilloscope')
            xlabel(app.UIAxes, 'Time, (s)')
            ylabel(app.UIAxes, 'Voltage, (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8];
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Channel1SwitchLabel = uilabel(app.UIFigure);
            app.Channel1SwitchLabel.Text = 'Channel 1';
            app.Channel1Switch = uiswitch(app.UIFigure, 'slider');
            app.Channel1Switch.ValueChangedFcn = createCallbackFcn(app, @Channel1SwitchValueChanged, true);
            app.Channel2SwitchLabel = uilabel(app.UIFigure);
            app.Channel2SwitchLabel.Text = 'Channel 2';
            app.Channel2Switch = uiswitch(app.UIFigure, 'slider');
            app.Channel2Switch.ValueChangedFcn = createCallbackFcn(app, @Channel2SwitchValueChanged, true);
            app.Position1Knob_2Label = uilabel(app.UIFigure);
            app.Position1Knob_2Label.Text = 'Position 1';
            app.Position2Knob_2Label = uilabel(app.UIFigure);
            app.Position2Knob_2Label.Text = 'Position 2';
            app.Position2Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Position2Knob_2.Limits = [-5 5];
            app.Position2Knob_2.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Position2Knob_2.MinorTicks = [];
            app.Position1Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Position1Knob_2.Limits = [-5 5];
            app.Position1Knob_2.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Position1Knob_2.MinorTicks = [];
            app.Scale1Knob_2Label = uilabel(app.UIFigure);
            app.Scale1Knob_2Label.Text = 'Scale 1';
            app.Scale1Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Scale1Knob_2.Limits = [0 20];
            app.Scale1Knob_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.Scale1Knob_2.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.Scale1Knob_2.MinorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.Scale2Knob_2Label = uilabel(app.UIFigure);
            app.Scale2Knob_2Label.Text = 'Scale 2';
            app.Scale2Knob_2 = uiknob(app.UIFigure, 'continuous');
            app.Scale2Knob_2.Limits = [0 20];
            app.Scale2Knob_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.Scale2Knob_2.MinorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.Position1KnobLabel = uilabel(app.UIFigure);
            app.Position1KnobLabel.Text = 'Position 1';
            app.Position1Knob = uiknob(app.UIFigure, 'continuous');
            app.Position1Knob.Limits = [0 8];
            app.Scale1KnobLabel = uilabel(app.UIFigure);
            app.Scale1KnobLabel.Text = 'Scale 1';
            app.Scale1Knob = uiknob(app.UIFigure, 'continuous');
            app.Scale1Knob.Limits = [0 2];
            app.Position2KnobLabel = uilabel(app.UIFigure);
            app.Position2KnobLabel.Text = 'Position 2';
            app.Position2Knob = uiknob(app.UIFigure, 'continuous');
            app.Position2Knob.Limits = [0 8];
            app.Scale2KnobLabel = uilabel(app.UIFigure);
            app.Scale2KnobLabel.Text = 'Scale 2';
            app.Scale2Knob = uiknob(app.UIFigure, 'continuous');
            app.Scale2Knob.Limits = [0 2];
            app.VerticalLabel = uilabel(app.UIFigure);
            app.VerticalLabel.Text = 'Vertical';
            app.HorizontalLabel = uilabel(app.UIFigure);
            app.HorizontalLabel.Text = 'Horizontal';
            app.TriggerKnobLabel = uilabel(app.UIFigure);
            app.TriggerKnobLabel.Text = 'Trigger';
            app.TriggerKnob = uiknob(app.UIFigure, 'continuous');
            app.TriggerKnob.Limits = [-5 5];
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.waveform1Label = uilabel(app.UIFigure);
            app.waveform1Label.Text = 'waveform 1';
            app.waveform1DropDown = uidropdown(app.UIFigure);
            app.waveform1DropDown.Items = {'sine', 'square', 'sawtooth', 'constant'};
            app.waveform1DropDown.ValueChangedFcn = createCallbackFcn(app, @waveform1DropDownValueChanged, true);
            app.waveform1DropDown.ClickedFcn = createCallbackFcn(app, @waveform1DropDownClicked, true);
            app.waveform1DropDown.Value = 'sine';
            app.waveform2DropDownLabel = uilabel(app.UIFigure);
            app.waveform2DropDownLabel.Text = 'waveform 2';
            app.waveform2DropDown = uidropdown(app.UIFigure);
            app.waveform2DropDown.Items = {'sine', 'square', 'sawtooth', 'constant'};
            app.waveform2DropDown.Value = 'sine';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.Frequency1SliderLabel = uilabel(app.UIFigure);
            app.Frequency1SliderLabel.Text = 'Frequency 1';
            app.Frequency1Slider = uislider(app.UIFigure);
            app.Frequency2SliderLabel = uilabel(app.UIFigure);
            app.Frequency2SliderLabel.Text = 'Frequency 2';
            app.Frequency2Slider = uislider(app.UIFigure);
            app.Amplitude1SliderLabel = uilabel(app.UIFigure);
            app.Amplitude1SliderLabel.Text = 'Amplitude 1';
            app.Amplitude1Slider = uislider(app.UIFigure);
            app.Amplitude2Slider_2Label = uilabel(app.UIFigure);
            app.Amplitude2Slider_2Label.Text = 'Amplitude 2';
            app.Amplitude2Slider_2 = uislider(app.UIFigure);
            app.WaveformGeneratorLabel = uilabel(app.UIFigure);
            app.WaveformGeneratorLabel.Text = 'Waveform Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end